classdef App_from_lesson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Lamp            matlab.ui.control.Lamp
        LampLabel       matlab.ui.control.Label
        TextArea2       matlab.ui.control.TextArea
        TextArea2Label  matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        TextAreaLabel   matlab.ui.control.Label
        StopButton      matlab.ui.control.Button
        StartButton     matlab.ui.control.Button
        Subtitle        matlab.ui.control.Label
        Title           matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
        SerialPort
        Val
    end

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            % Generate a random number
            randnum = rand;
    
            % Update YData in plot
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,1);
            ydata(1) = randnum;
            app.PlotLine.YData = ydata;
        end        

        function Serial_handler(app,~,~)
            %countr = 1;
            % data(countr) = read(app.SerialPort, 1, "char");
            % while(data(countr) ~= '\0')
            %     countr = countr + 1;
            %     data(countr) = read(app.SerialPort, 1, "char");
            % end
            % 
            % ready_data = data(1:num-3);
            % num = str2num(ready_data);
            %num = read(app.SerialPort, 1, "char");
            num = readline(app.SerialPort);
            display(num);
            assignin('base','name',num);
            %nenum = convertCharsToStrings(num);

            app.TextArea.Value = num;
    
            % Update YData in plot
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,-1);
            ydata(end) = num;
            app.PlotLine.YData = ydata;
       end     
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure x- and y- axis
            app.UIAxes.XLim = [0 60];
            %app.UIAxes.XDir = "reverse";
            app.UIAxes.YLim = [0 1023];
            
            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            
            % Create timer object
            % app.RandTimer = timer(...
            %     "ExecutionMode", "fixedRate", ...    % Run timer repeatedly
            %     "Period", 1, ...                     % Period is 1 second
            %     "BusyMode", "queue",...              % Queue timer callbacks when busy
            %     "TimerFcn", @app.RandTimerFcn);      % Specify callback function
            % 
           % app.SerialPort = serialport("COM5", 2400);
           % %app.SerialPort.BaudRate = 2400;
           % configureTerminator(app.SerialPort,"LF");
           % %app.SerialPort.BytesAvailableFcnMode = "terminator";
           % %configureCallback(app.SerialPort,"terminator",@app.Serial_handler)
           % configureCallback(app.SerialPort,"byte",1,@app.Serial_handler);
           % %app.SerialPort.BytesAvailableFcn = @app.Serial_handler;


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           app.SerialPort = serialport("COM5", 2400);
           configureTerminator(app.SerialPort,"LF");
           %configureCallback(app.SerialPort,"byte",1,@app.Serial_handler);
           configureCallback(app.SerialPort,"terminator",@app.Serial_handler)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            delete(app.SerialPort);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.SerialPort);
            delete(app);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            app.Val = app.TextArea.Value;
            app.TextArea2.Value = app.Val;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Random Number Generator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Random number calculated')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Output of rand';
            app.Subtitle = uilabel(app.UIFigure);
            app.Subtitle.Text = 'Calculated Every Second';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_from_lesson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end