classdef lab5_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClearButton                  matlab.ui.control.Button
        ButtonPause                  matlab.ui.control.Button
        ACPmodeButtonGroup           matlab.ui.container.ButtonGroup
        bit10Button                  matlab.ui.control.RadioButton
        bit8Button                   matlab.ui.control.RadioButton
        WorkingmodeButtonGroup       matlab.ui.container.ButtonGroup
        LowerButton                  matlab.ui.control.RadioButton
        AllButton                    matlab.ui.control.RadioButton
        HigherButton                 matlab.ui.control.RadioButton
        TresholdSpinner              matlab.ui.control.Spinner
        TresholdSpinnerLabel         matlab.ui.control.Label
        NumberofSamplesSpinner       matlab.ui.control.Spinner
        NumberofSamplesSpinnerLabel  matlab.ui.control.Label
        ConnectCOMButton             matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        ButtonStop                   matlab.ui.control.Button
        ButtonStart                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ComPort % Description
        UARTTimer % Description
        Signal % Description
        Connected
        Treshold
        ACPmode
        NumOfSamp
        WorkMode
        Command
        UpdateTimer
        DoneSamp
        Pause
    end
    
    methods (Access = private)
        
        function UARTTimerFunc(app,~,~)
            sread = readline(app.ComPort);

            ydata = app.Signal.YData;
            ydata = circshift(ydata,-1);
            ydata(end) = sread;
            app.Signal.YData = ydata;
                        
        end

        function Update(app,~,~)
            % sread = " ";
            % while sread == " "
            %     writeline(app.ComPort, "1");
            %     sread = readline(app.ComPort);
            % end  

            if (app.Command == 1)               
                writeline(app.ComPort, "c1");
                sread = readline(app.ComPort);
                if (app.DoneSamp >= app.NumOfSamp)
                    stop(app.UpdateTimer);
                    app.ButtonStop.ButtonPushedFcn;
                else
                    ydata = app.Signal.YData;
                    ydata = circshift(ydata,-1);
                    ydata(end) = sread;
                    app.Signal.YData = ydata;                
                    app.DoneSamp = app.DoneSamp + 1;
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.ComPort = serialport("COM11", 2400);
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 1023];   
            app.Connected = 0;
            app.Treshold = 10;
            app.ACPmode = 2;
            app.NumOfSamp = 222;
            app.WorkMode = 2;
            app.Command = 0;
            app.DoneSamp = 0;
           
            app.Signal = plot(app.UIAxes,0:100,zeros(1,101));
        
            app.UARTTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.05, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.UARTTimerFunc);      % Specify callback function

            app.UpdateTimer = timer(...
                'ExecutionMode','fixedRate', ...
                'Period', 0.1, ...
                'BusyMode', 'queue', ...
                'TimerFcn', @app.Update);
        end

        % Button pushed function: ButtonStart
        function BtnStartPush(app, event)
            app.ButtonStart.Enable = "off";
            app.ButtonPause.Enable = "on";
            app.ButtonStop.Enable = "on";
            app.ConnectCOMButton.Enable = "off";  

            if (app.WorkMode == 2)
                sendtxt = "n" + int2str(app.NumOfSamp);
                writeline(app.ComPort, sendtxt);

                sendtxt = "a" + int2str(app.ACPmode);
                writeline(app.ComPort, sendtxt);

                sendtxt = "w" + app.WorkMode;
                writeline(app.ComPort, sendtxt);

                writeline(app.ComPort, "c1");
            elseif (app.WorkMode == 1) || (app.WorkMode == 3)
                sendtxt = "n" + int2str(app.NumOfSamp);
                writeline(app.ComPort, sendtxt);
                
                sendtxt = "t" + int2str(app.Treshold);
                writeline(app.ComPort, sendtxt);

                sendtxt = "a" + int2str(app.ACPmode);
                writeline(app.ComPort, sendtxt);

                sendtxt = "w" + int2str(app.WorkMode);
                writeline(app.ComPort, sendtxt);

                writeline(app.ComPort, "c1");
            end
            app.DoneSamp = 0;
            app.Command = 1;
            app.Pause = 0;
            start(app.UpdateTimer);

        end

        % Button pushed function: ButtonStop
        function BtnStopPush(app, event)
            app.ButtonStart.Enable = "on";
            app.ButtonPause.Enable = "off";
            app.ButtonStop.Enable = "off";
            app.ConnectCOMButton.Enable = "on";

            writeline(app.ComPort, "c3");
            app.DoneSamp = 0;
            app.Command = 3;
            stop(app.UpdateTimer);
        end

        % Button pushed function: ConnectCOMButton
        function ConnectButton(app, event)
            if app.Connected == 0
                app.ComPort = serialport("COM11", 2400);
                app.Lamp.Color = [0 1 0];
                app.ConnectCOMButton.Text = "Disconnect COM";
                app.ButtonStart.Enable = "on";
                app.Connected = 1;                
            else
                delete(app.ComPort);
                app.Lamp.Color = [1 0 0];
                app.ConnectCOMButton.Text = "Connect COM";
                app.ButtonStart.Enable = "off";
                app.Connected = 0;
            end
        end

        % Selection changed function: WorkingmodeButtonGroup
        function WorkingModeChange(app, event)
            selectedButton = app.WorkingmodeButtonGroup.SelectedObject;
            if selectedButton == app.HigherButton
                app.WorkMode = 1;
                app.TresholdSpinner.Enable = 'on';
            elseif selectedButton == app.AllButton
                app.WorkMode = 2;
                app.TresholdSpinner.Enable = 'off';
                app.Treshold = 0;
            else
                app.WorkMode = 3; 
                app.TresholdSpinner.Enable = 'on';
            end
        end

        % Selection changed function: ACPmodeButtonGroup
        function ACPmodeChange(app, event)
            selectedButton = app.ACPmodeButtonGroup.SelectedObject;
            if (selectedButton == app.bit8Button)
                app.ACPmode = 1;
                app.TresholdSpinner.Limits = [1, 254];
                app.UIAxes.YLim = [0 255];
            elseif (selectedButton == app.bit10Button)
                app.ACPmode = 2;
                app.TresholdSpinner.Limits = [1, 1023];
                app.UIAxes.YLim = [0 1023];
            end
        end

        % Button pushed function: ButtonPause
        function ButtonPausePushed(app, event)
            if (app.Pause == 0)
                app.Command = 2;                
                app.Pause = 1;
                app.ButtonPause.Text = "Unpause";
            else
                app.Command = 1;
                app.Pause = 0;
                app.ButtonPause.Text = "Pause";
            end
        end

        % Value changed function: NumberofSamplesSpinner
        function NumOfSampChange(app, event)
            value = app.NumberofSamplesSpinner.Value;
            app.NumOfSamp = value;
        end

        % Value changed function: TresholdSpinner
        function TresholdChange(app, event)
            value = app.TresholdSpinner.Value;
            app.Treshold = value;
        end

        % Button pushed function: ClearButton
        function ClearPlotButtonPush(app, event)
            app.Signal = plot(app.UIAxes,0:100,zeros(1,101));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonStart = uibutton(app.UIFigure, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @BtnStartPush, true);
            app.ButtonStart.Enable = 'off';
            app.ButtonStart.Text = 'Start';
            app.ButtonStop = uibutton(app.UIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @BtnStopPush, true);
            app.ButtonStop.Enable = 'off';
            app.ButtonStop.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.ConnectCOMButton = uibutton(app.UIFigure, 'push');
            app.ConnectCOMButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton, true);
            app.ConnectCOMButton.Text = 'Connect COM';
            app.NumberofSamplesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofSamplesSpinnerLabel.Text = 'Number of Samples';
            app.NumberofSamplesSpinner = uispinner(app.UIFigure);
            app.NumberofSamplesSpinner.Limits = [1 9999];
            app.NumberofSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumOfSampChange, true);
            app.NumberofSamplesSpinner.Value = 222;
            app.TresholdSpinnerLabel = uilabel(app.UIFigure);
            app.TresholdSpinnerLabel.Enable = 'off';
            app.TresholdSpinnerLabel.Text = 'Treshold';
            app.TresholdSpinner = uispinner(app.UIFigure);
            app.TresholdSpinner.Limits = [1 1022];
            app.TresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @TresholdChange, true);
            app.TresholdSpinner.Enable = 'off';
            app.TresholdSpinner.Value = 1;
            app.WorkingmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.WorkingmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WorkingModeChange, true);
            app.WorkingmodeButtonGroup.Title = 'Working mode';
            app.HigherButton = uiradiobutton(app.WorkingmodeButtonGroup);
            app.HigherButton.Text = 'Higher';
            app.AllButton = uiradiobutton(app.WorkingmodeButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.LowerButton = uiradiobutton(app.WorkingmodeButtonGroup);
            app.LowerButton.Text = 'Lower';
            app.ACPmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.ACPmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ACPmodeChange, true);
            app.ACPmodeButtonGroup.Title = 'ACP mode';
            app.bit8Button = uiradiobutton(app.ACPmodeButtonGroup);
            app.bit8Button.Text = '8bit';
            app.bit10Button = uiradiobutton(app.ACPmodeButtonGroup);
            app.bit10Button.Text = '10bit';
            app.bit10Button.Value = true;
            app.ButtonPause = uibutton(app.UIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @ButtonPausePushed, true);
            app.ButtonPause.Enable = 'off';
            app.ButtonPause.Text = 'Pause';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPush, true);
            app.ClearButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end