classdef App_from_lesson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button2   matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Lamp      matlab.ui.control.Lamp
        Label     matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
        SerialPort
        Val
    end

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            % Generate a random number
            randnum = rand;
    
            % Update YData in plot
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,1);
            ydata(1) = randnum;
            app.PlotLine.YData = ydata;
        end        

        function Serial_handler(app,~,~)
            %countr = 1;
            % data(countr) = read(app.SerialPort, 1, "char");
            % while(data(countr) ~= '\0')
            %     countr = countr + 1;
            %     data(countr) = read(app.SerialPort, 1, "char");
            % end
            % 
            % ready_data = data(1:num-3);
            % num = str2num(ready_data);
            %num = read(app.SerialPort, 1, "char");
            num = readline(app.SerialPort);
            display(num);
            assignin('base','name',num);
            %nenum = convertCharsToStrings(num);

            app.TextArea.Value = num;
    
            % Update YData in plot
            ydata = app.PlotLine.YData;
            ydata = circshift(ydata,-1);
            ydata(end) = num;
            app.PlotLine.YData = ydata;
       end     
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure x- and y- axis
            app.UIAxes.XLim = [0 60];
            %app.UIAxes.XDir = "reverse";
            app.UIAxes.YLim = [0 1023];
            
            % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:60,zeros(1,61));
            
            % Create timer object
            % app.RandTimer = timer(...
            %     "ExecutionMode", "fixedRate", ...    % Run timer repeatedly
            %     "Period", 1, ...                     % Period is 1 second
            %     "BusyMode", "queue",...              % Queue timer callbacks when busy
            %     "TimerFcn", @app.RandTimerFcn);      % Specify callback function
            % 
           % app.SerialPort = serialport("COM5", 2400);
           % %app.SerialPort.BaudRate = 2400;
           % configureTerminator(app.SerialPort,"LF");
           % %app.SerialPort.BytesAvailableFcnMode = "terminator";
           % %configureCallback(app.SerialPort,"terminator",@app.Serial_handler)
           % configureCallback(app.SerialPort,"byte",1,@app.Serial_handler);
           % %app.SerialPort.BytesAvailableFcn = @app.Serial_handler;


        end

        % Callback function: not associated with a component
        function StartButtonPushed(app, event)
           app.SerialPort = serialport("COM5", 2400);
           configureTerminator(app.SerialPort,"LF");
           %configureCallback(app.SerialPort,"byte",1,@app.Serial_handler);
           configureCallback(app.SerialPort,"terminator",@app.Serial_handler)
        end

        % Callback function: not associated with a component
        function StopButtonPushed(app, event)

            delete(app.SerialPort);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.SerialPort);
            delete(app);
        end

        % Callback function: not associated with a component
        function TextAreaValueChanged(app, event)
            app.Val = app.TextArea.Value;
            app.TextArea2.Value = app.Val;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Random Number Generator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Random number calculated')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_from_lesson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end