classdef lab5_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MeasurementButtonGroup  matlab.ui.container.ButtonGroup
        HumidityButton          matlab.ui.control.RadioButton
        FahrenheitButton        matlab.ui.control.RadioButton
        CelsiusButton           matlab.ui.control.RadioButton
        ClearButton             matlab.ui.control.Button
        ConnectCOMButton        matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        Label                   matlab.ui.control.Label
        ButtonStart             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ComPort % Description
        UARTTimer % Description
        Signal % Description
        UpdateTimer
        StartFlag
        Connected
        Measurement
    end
    
    methods (Access = private)
        
        % function UARTTimerFunc(app,~,~)
        %     sread = readline(app.ComPort);            
        %     if (app.Measurement == 1)
        %         Temp = -45 + (175 * temp / 65535.0);
        %     elseif (app.Measurement == 2)
        %         Temp = -49 + (315 * temp / 65535.0);
        %     ydata = app.Signal.YData;
        %     ydata = circshift(ydata,-1);
        %     ydata(end) = Temp;
        %     app.Signal.YData = ydata;
        % 
        % end

        function Update(app,~,~)
            if ((app.Measurement == 1) || (app.Measurement == 2))
                writeline(app.ComPort, "c2");
            elseif (app.Measurement == 3)
                writeline(app.ComPort, "c4");
            end
            sread = readline(app.ComPort); 
            sread = str2num(sread);
            display(sread);
            Temp = 0;
            if (app.Measurement == 1)
                Temp = -45 + (175 * sread / 65535.0);
            elseif (app.Measurement == 2)
                Temp = -49 + (315 * sread / 65535.0);
            elseif (app.Measurement == 3)
                Temp = 100 * sread / 65535.0;
            end
            ydata = app.Signal.YData;
            ydata = circshift(ydata,-1);
            ydata(end) = Temp;
            app.Signal.YData = ydata;
           

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 65];   
            app.StartFlag = 0;
            app.Connected = 0;
            app.Measurement = 1;
           
            app.Signal = plot(app.UIAxes,0:100,zeros(1,101));
        
            app.UARTTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.05, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.UARTTimerFunc);      % Specify callback function

            app.UpdateTimer = timer(...
                'ExecutionMode','fixedRate', ...
                'Period', 0.25, ...
                'BusyMode', 'queue', ...
                'TimerFcn', @app.Update);
        end

        % Button pushed function: ButtonStart
        function BtnStartPush(app, event)
            if (app.StartFlag == 0)
                app.ButtonStart.Text = "Stop";
                app.ConnectCOMButton.Enable = "off";              
                start(app.UpdateTimer);
                app.StartFlag = 1;
            elseif (app.StartFlag == 1)
                app.ButtonStart.Text = "Start";
                app.ConnectCOMButton.Enable = "on";              
                stop(app.UpdateTimer);
                app.StartFlag = 0;
            end
        end

        % Button pushed function: ConnectCOMButton
        function ConnectButton(app, event)
            if app.Connected == 0
                app.ComPort = serialport("COM8", 2400);
                app.Lamp.Color = [0 1 0];                
                app.ButtonStart.Enable = "on";
                app.Connected = 1;
                writeline(app.ComPort, "c1");

                app.ConnectCOMButton.Text = "Disconnect COM";
            else
                writeline(app.ComPort, "c3");
                delete(app.ComPort);
                app.Lamp.Color = [1 0 0];
                app.ButtonStart.Enable = "off";
                app.Connected = 0;
                app.ConnectCOMButton.Text = "Connect COM";
            end
        end

        % Button pushed function: ClearButton
        function ClearPlotButtonPush(app, event)
            app.Signal = plot(app.UIAxes,0:100,zeros(1,101));
        end

        % Selection changed function: MeasurementButtonGroup
        function MeasurementChange(app, event)
            selectedButton = app.MeasurementButtonGroup.SelectedObject;
            if (selectedButton == app.CelsiusButton)
                app.Measurement = 1;
                app.UIAxes.YLim = [0 65];
            elseif (selectedButton == app.FahrenheitButton)
                app.Measurement = 2;
                app.UIAxes.YLim = [32 149];
            elseif (selectedButton == app.HumidityButton)
                app.Measurement = 2;
                app.UIAxes.YLim = [0 100];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonStart = uibutton(app.UIFigure, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @BtnStartPush, true);
            app.ButtonStart.Enable = 'off';
            app.ButtonStart.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.ConnectCOMButton = uibutton(app.UIFigure, 'push');
            app.ConnectCOMButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton, true);
            app.ConnectCOMButton.Text = 'Connect COM';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPush, true);
            app.ClearButton.Text = 'Clear Plot';
            app.MeasurementButtonGroup = uibuttongroup(app.UIFigure);
            app.MeasurementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MeasurementChange, true);
            app.MeasurementButtonGroup.Title = 'Measurement';
            app.CelsiusButton = uiradiobutton(app.MeasurementButtonGroup);
            app.CelsiusButton.Text = 'Celsius';
            app.CelsiusButton.Value = true;
            app.FahrenheitButton = uiradiobutton(app.MeasurementButtonGroup);
            app.FahrenheitButton.Text = 'Fahrenheit';
            app.HumidityButton = uiradiobutton(app.MeasurementButtonGroup);
            app.HumidityButton.Text = 'Humidity';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end